classdef Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MainDisplayPanel             matlab.ui.container.Panel
        AButton                      matlab.ui.control.StateButton
        BButton                      matlab.ui.control.StateButton
        CButton                      matlab.ui.control.StateButton
        DButton                      matlab.ui.control.StateButton
        NEXTButton                   matlab.ui.control.Button
        YourcurrentanswerLabel       matlab.ui.control.Label
        ANSDISP                      matlab.ui.control.TextArea
        QDISP                        matlab.ui.control.TextArea
        STARTButton                  matlab.ui.control.StateButton
        YourScoreEditFieldLabel      matlab.ui.control.Label
        Scorefield                   matlab.ui.control.NumericEditField
        CreateFile                   matlab.ui.control.Button
        EnterYourNameEditFieldLabel  matlab.ui.control.Label
        ProfileName                  matlab.ui.control.EditField
        NOSPACESPLEASELabel          matlab.ui.control.Label
        EButton                      matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        UIanswers = {}; % The array in which the user's answers are stored%
        Qarray = {}; %The array in which the question is stored%
        Aarray = {}; %The array in which the corresponding answers to said questions are stored%
        count = 0; %A counter variable: indicates which question to pull in sequential order%
        score; %The user's score%
        Qtot; %The total number of questions that the user will answer%
        fid; %where the file ID will be stored%
        filenamedata; %the concatenated .dat file where the user profile will be stored%
    end

    methods (Access = public)
    
        function results = startupfn(app)
            
            app.UIanswers = {'0'}; %pre-allocating the array in which UI will be stored%
            for k = 2:20
                app.UIanswers{k} = '0';
            end
           
        end
    end

    methods (Access = private)

        % Value changed function: AButton
        function AButtonValueChanged(app, event)
            fans = 'A'; %stores 'A' after the button is pressed%
            app.ANSDISP.Value = fans; %value is then stored in text box%
            
        end

        % Value changed function: BButton
        function BButtonValueChanged(app, event)
            fans = 'B'; %stores 'B' after the button is pressed%
             app.ANSDISP.Value = fans; %value is then stored in text box%
        end

        % Value changed function: CButton
        function CButtonValueChanged(app, event)
            fans = 'C'; %stores 'C' after the button is pressed%
             app.ANSDISP.Value = fans; %value is then stored in text box%
        end

        % Value changed function: DButton
        function DButtonValueChanged(app, event)
            fans = 'D'; %stores 'D' after the button is pressed%
             app.ANSDISP.Value = fans; %value is then stored in text box%
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
        if (app.count <21) %for all 20 questions, the value in "your current answer" is stored in the UI array%
            app.UIanswers{app.count} = char(app.ANSDISP.Value);
        end
            if (app.AButton.Value == 1 || app.BButton.Value == 1 || app.CButton.Value == 1 ...
                   || app.DButton.Value == 1 || app.EButton.Value == 1 || app.STARTButton.Value == 1)
               app.AButton.Value = 0;
               app.BButton.Value = 0;
               app.CButton.Value = 0;
               app.DButton.Value = 0;
               app.EButton.Value = 0;
               app.STARTButton.Value = 0;
               app.ANSDISP.Value = '';
               app.QDISP.Value = '';
            
            end %resets all text fields/button presses after 'NEXT' is hit%
            app.count = app.count+1; %counter is incremented by 1%
            
            if (app.count <=20) %for all 20 questions, the question within "Qarray" wil be stored in the question display%
                app.QDISP.Value = app.Qarray{app.count};
            else
                app.score = scorecalc(app.UIanswers, app.Aarray, app.Qtot); %after 20 questions, score will be calculated%
                    app.QDISP.Value = 'The exam is now over';
                    app.Scorefield.Value = app.score;
                        if (app.score == 100)
                            app.QDISP.Value = 'Bro you''re awesome'; %congratulatory message for recieving a perfect score%
                        end
             z = app.score;
             fprintf(app.fid, ' %d', app.score); %writes the score to the user's profile%
             fclose(app.fid);
             app.fid = fopen(app.filenamedata, 'r');
             c = textscan(app.fid, '%d', 10); %reads the score as a integer value instead of text%
             scoreplotted = c{1}' %transposes the vector pulled from the cell array storing the integer values%
             xaxis = [1:length(scoreplotted)]; %currently half-functional, doesn't properly display the amount of times the exam was taken%
             plot(app.UIAxes,xaxis,scoreplotted); %plots the user's scores from user profile%
            
            end


        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
       [nums,txt] = xlsread('master1.xlsx'); %initializes the excel spreadsheet, begins to read from it and divides entries into numerical and text values%
            indexQ = randi([2,1411],1,20); %pulls a nonsequential order of random questions%
            app.count = 1; %initialization of counter variable%
            app.Qtot = 20; %total number of questions%
            for i = 1:20 %for loop that pulls the questions and their corresponding answer keys based on the generated values%
                x = indexQ(i);
                app.Qarray{i} = txt{x,10};
                app.Aarray{i} = txt{x,4};
            end
           
            question = app.Qarray{app.count};
            app.QDISP.Value = question;
        end

        % Value changed function: ProfileName
        function ProfileNameValueChanged(app, event)
            if (ischar(app.ProfileName.Value) == 1) %determines whether there is actual text in the field%
                app.CreateFile.Enable = 'on'; %enables the "CreateFile" button%
            end
            
        end

        % Button pushed function: CreateFile
        function CreateFileButtonPushed(app, event)
            app.filenamedata = [app.ProfileName.Value, '.dat']; %concatenates the user input and the .dat extension%
            app.fid = fopen(app.filenamedata,'a'); %creates a file/appends to a file if the user uses the same profilename%
            if app.fid == -1 %error check%
                error('file cannot be opened');
            end
            
        end

        % Value changed function: EButton
        function EButtonValueChanged(app, event)
            fans = 'E'; %stores 'E' after the button is pressed%
             app.ANSDISP.Value = fans; %value is then stored in text box%
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainDisplayPanel = uipanel(app.UIFigure);
            app.MainDisplayPanel.Title = 'Main Display';
            app.AButton = uibutton(app.MainDisplayPanel, 'state');
            app.AButton.ValueChangedFcn = createCallbackFcn(app, @AButtonValueChanged, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.MainDisplayPanel, 'state');
            app.BButton.ValueChangedFcn = createCallbackFcn(app, @BButtonValueChanged, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.MainDisplayPanel, 'state');
            app.CButton.ValueChangedFcn = createCallbackFcn(app, @CButtonValueChanged, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.MainDisplayPanel, 'state');
            app.DButton.ValueChangedFcn = createCallbackFcn(app, @DButtonValueChanged, true);
            app.DButton.Text = 'D';
            app.NEXTButton = uibutton(app.MainDisplayPanel, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.YourcurrentanswerLabel = uilabel(app.MainDisplayPanel);
            app.YourcurrentanswerLabel.Text = 'Your current answer:';
            app.ANSDISP = uitextarea(app.MainDisplayPanel);
            app.ANSDISP.Editable = 'off';
            app.QDISP = uitextarea(app.MainDisplayPanel);
            app.QDISP.Editable = 'off';
            app.STARTButton = uibutton(app.MainDisplayPanel, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.YourScoreEditFieldLabel = uilabel(app.MainDisplayPanel);
            app.YourScoreEditFieldLabel.Text = 'Your Score';
            app.Scorefield = uieditfield(app.MainDisplayPanel, 'numeric');
            app.Scorefield.Editable = 'off';
            app.CreateFile = uibutton(app.MainDisplayPanel, 'push');
            app.CreateFile.ButtonPushedFcn = createCallbackFcn(app, @CreateFileButtonPushed, true);
            app.CreateFile.Enable = 'off';
            app.CreateFile.Text = 'Create/Open Profile!';
            app.EnterYourNameEditFieldLabel = uilabel(app.MainDisplayPanel);
            app.EnterYourNameEditFieldLabel.Text = 'Enter Your Name';
            app.ProfileName = uieditfield(app.MainDisplayPanel, 'text');
            app.ProfileName.ValueChangedFcn = createCallbackFcn(app, @ProfileNameValueChanged, true);
            app.NOSPACESPLEASELabel = uilabel(app.MainDisplayPanel);
            app.NOSPACESPLEASELabel.Text = 'NO SPACES PLEASE!';
            app.EButton = uibutton(app.MainDisplayPanel, 'state');
            app.EButton.ValueChangedFcn = createCallbackFcn(app, @EButtonValueChanged, true);
            app.EButton.Text = 'E';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scores')
            xlabel(app.UIAxes, 'Exam Number')
            ylabel(app.UIAxes, 'Score')
            app.UIAxes.XTick = [1 2 3 4 5];
            app.UIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.UIAxes.YTick = [0 50 100];
            app.UIAxes.YTickLabel = {'0'; '50'; '100'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end